classdef INFOMASK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        BOB       matlab.ui.control.Button
        JANE      matlab.ui.control.Button
        JILL      matlab.ui.control.Button
        LYNN      matlab.ui.control.Button
        MIKE      matlab.ui.control.Button
        PAT       matlab.ui.control.Button
        BOUGHT    matlab.ui.control.Button
        FOUND     matlab.ui.control.Button
        GAVE      matlab.ui.control.Button
        HELD      matlab.ui.control.Button
        LOST      matlab.ui.control.Button
        SAW       matlab.ui.control.Button
        TWO       matlab.ui.control.Button
        THREE     matlab.ui.control.Button
        FOUR      matlab.ui.control.Button
        FIVE      matlab.ui.control.Button
        SIX       matlab.ui.control.Button
        EIGHT     matlab.ui.control.Button
        BIG       matlab.ui.control.Button
        CHEAP     matlab.ui.control.Button
        GREEN     matlab.ui.control.Button
        HOT       matlab.ui.control.Button
        NEW       matlab.ui.control.Button
        OLD       matlab.ui.control.Button
        BAGS      matlab.ui.control.Button
        CARDS     matlab.ui.control.Button
        GLOVES    matlab.ui.control.Button
        HATS      matlab.ui.control.Button
        PENS      matlab.ui.control.Button
        SHOES     matlab.ui.control.Button
        Info      matlab.ui.control.Label
        TOYS      matlab.ui.control.Button
        SMALL     matlab.ui.control.Button
        TEN       matlab.ui.control.Button
        SOCKS     matlab.ui.control.Button
        RED       matlab.ui.control.Button
        NINE      matlab.ui.control.Button
        TOOK      matlab.ui.control.Button
        SOLD      matlab.ui.control.Button
        SUE       matlab.ui.control.Button
        SAM       matlab.ui.control.Button
        Start     matlab.ui.control.Button
    end

    
    properties (Access = private)
        ntrials  = 10  % total number of trials
        response = zeros(1, 5); % Holds the response for each of 5 columns for current trial
        currtarget % The correct response set for current trial
        SNRlist = 12:-3:-54;
        SNRstep1 = 6;
        SNRstep2 = 3;
        snrcount = zeros(23, 1);
        trialaudiopath
        fs
        maxtrials = 60; % Max number of trials after which adaptive tracking exits even without convergence
        maxreversals = 14; % Number of reversals after which convergence is considered to be reached
        targetlist  % The list of targets
        resplist   % The list of responses
        currtrial % What is the trial number?
        currsignal % Audio for current trial
        currsnr % SNR for current trial
        f1RZ % ActiveX figure for TDT soundcard
        RZ  % Soundcard object
        level % Sound level of target sound
        useTDT  % Whether or not to use the TDT
        feedbackdur % Duration for which to show feedback
        subj % Current subject ID
        respDir % Where to save response results
        criterionCorrect; % How many do they have to get correct to keep decreasing SNR
        currreversals = 0;
        prevtrial = true;
        presentedSNR = [];
        ncorrectList = [];
        data
    end
    
    methods (Access = private)
        
        function enableColumn(app, colnum)
            % Enables one column of buttons and disables rest
            col1action = 'off';
            col2action = 'off';
            col3action = 'off';
            col4action = 'off';
            col5action = 'off';
            switch colnum
                case 1
                    col1action = 'on';
                case 2
                    col2action = 'on';
                case 3
                    col3action = 'on';
                case 4
                    col4action = 'on';
                case 5
                    col5action = 'on';
            end
            % Column 1
            app.BOB.Enable = col1action;
            app.JANE.Enable = col1action;
            app.JILL.Enable = col1action;
            app.LYNN.Enable = col1action;
            app.MIKE.Enable = col1action;
            app.PAT.Enable = col1action;
            app.SAM.Enable = col1action;
            app.SUE.Enable = col1action;
            % Column 2
            app.BOUGHT.Enable = col2action;
            app.FOUND.Enable = col2action;
            app.GAVE.Enable = col2action;
            app.HELD.Enable = col2action;
            app.LOST.Enable = col2action;
            app.SAW.Enable = col2action;
            app.SOLD.Enable = col2action;
            app.TOOK.Enable = col2action;
            % Column 3
            app.TWO.Enable = col3action;
            app.THREE.Enable = col3action;
            app.FOUR.Enable = col3action;
            app.FIVE.Enable = col3action;
            app.SIX.Enable = col3action;
            app.EIGHT.Enable = col3action;
            app.NINE.Enable = col3action;
            app.TEN.Enable = col3action;
            % Column 4
            app.BIG.Enable = col4action;
            app.CHEAP.Enable = col4action;
            app.GREEN.Enable = col4action;
            app.HOT.Enable = col4action;
            app.NEW.Enable = col4action;
            app.OLD.Enable = col4action;
            app.RED.Enable = col4action;
            app.SMALL.Enable = col4action;
            % Column 5
            app.BAGS.Enable = col5action;
            app.CARDS.Enable = col5action;
            app.GLOVES.Enable = col5action;
            app.HATS.Enable = col5action;
            app.PENS.Enable = col5action;
            app.SHOES.Enable = col5action;
            app.SOCKS.Enable = col5action;
            app.TOYS.Enable = col5action;
        end
        
        function showfeedback(app)
            app.enableColumn(0); % Disable buttons
            app.resplist(app.currtrial, :) = app.response;
            ncorrect = sum(app.response == app.currtarget);
            feedback = sprintf('%d / 5 correct!', ncorrect);
            app.presentedSNR = [app.presentedSNR, app.currsnr];
            app.ncorrectList = [app.ncorrectList, ncorrect];
            app.Info.Text = feedback;
            pause(app.feedbackdur);
            app.Info.Text = 'Follow the voice that starts with "JANE" and ignore the others!';
            app.setupNextTrial(ncorrect);
        end
        
        function dat = loadcurrtrial(app, snr)
            audiodir = app.trialaudiopath;
            app.snrcount(app.SNRlist == snr) = app.snrcount(app.SNRlist == snr) + 1;
            k = app.snrcount(app.SNRlist == snr);
            fname = strcat(audiodir, 'INFOMASK_SNR', num2str(snr), '_trial_', num2str(k), '.mat');
            dat = load(fname);
        end
        
        function setupNextTrial(app, ncorrect)
            if ncorrect >= app.criterionCorrect
                if ~app.prevtrial
                    app.currreversals = app.currreversals + 1;
                    app.prevtrial = true;
                end
                if app.currreversals > 2
                    app.currsnr = app.currsnr - app.SNRstep2;
                else
                    app.currsnr = app.currsnr - app.SNRstep1;
                end
            else
                if app.prevtrial
                    app.currreversals = app.currreversals + 1;
                    app.prevtrial = false;
                end
                if app.currreversals > 2
                    app.currsnr = app.currsnr + app.SNRstep2;
                else
                    app.currsnr = app.currsnr + app.SNRstep1;
                end
            end
            if app.currsnr > 12
                app.currsnr = 12;
            end
            app.currtrial = app.currtrial + 1;
            
            theEnd = (app.currtrial > app.maxtrials) || (app.currreversals >= app.maxreversals);
            if ~theEnd
                dat = app.loadcurrtrial(app.currsnr);
                app.currsignal = dat.x;
                app.currtarget = dat.inds(1, :);
                app.StartButtonPushed();
            else
                app.endCredits();
            end
        end
        
        function endCredits(app)
            datetag = datestr(clock);
            datetag(strfind(datetag,' ')) = '_';
            datetag(strfind(datetag,':')) = '-';
            saveName = [app.respDir, '/MST_', app.subj, '_', datetag, '.mat'];
            data.resp.responseList = app.resplist;
            data.resp.presentedSNRs = app.presentedSNR;
            data.resp.ncorrList = app.ncorrectList;
            data.info = app.data.info; 
            save(saveName, 'data');
            % save(saveName, 'responseList', 'presentedSNRs', 'ncorrList');
            infoText = {'Done with current block!', 'Thank you!'};
            app.Info.Text = infoText;
            app.enableColumn(0);
            pause(2.0);
            close_play_circuit(app.f1RZ, app.RZ);
            app.UIFigure.CloseRequestFcn()
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.useTDT = true;
            if app.useTDT
                %% Setup sound card
                fig_num=99;
                USB_ch=1;
                IAC = -1;
                FS_tag = 3;
                [app.f1RZ,app.RZ,~]=load_play_circuit(FS_tag,fig_num,USB_ch,0,IAC);
            end
            
            %% Setup experiment details
            app.criterionCorrect = 4;
            app.level = 72;
            app.trialaudiopath = './trialaudio/';
            app.fs = 48828;
            app.feedbackdur = 1.0;
            startdat = load('./currsubj.mat');
            app.subj = startdat.subj;
            app.respDir = startdat.respDir;
            app.data.info = startdat.info; 
            
            % Start with first trial
            app.currtrial = 1;
            startSNR = 9;
            dat = app.loadcurrtrial(startSNR);
            app.currtarget = dat.inds(1, :);
            app.currsnr = startSNR;
            app.currsignal = dat.x;
            app.enableColumn(0); % Disable all columns
        end

        % Button pushed function: BAGS
        function BAGSButtonPushed(app, event)
            app.response(5) = 1;
            app.showfeedback();
        end

        % Button pushed function: BIG
        function BIGButtonPushed(app, event)
            app.response(4) = 1;
            app.enableColumn(5);
        end

        % Button pushed function: BOB
        function BOBPushed(app, event)
            app.response(1) = 1;
            app.enableColumn(2);
        end

        % Button pushed function: BOUGHT
        function BOUGHTButtonPushed(app, event)
            app.response(2) = 1;
            app.enableColumn(3);
        end

        % Button pushed function: CARDS
        function CARDSButtonPushed(app, event)
            app.response(5) = 2;
            app.showfeedback();
        end

        % Button pushed function: CHEAP
        function CHEAPButtonPushed(app, event)
            app.response(4) = 2;
            app.enableColumn(5);
        end

        % Button pushed function: EIGHT
        function EIGHTButtonPushed(app, event)
            app.response(3) = 6;
            app.enableColumn(4);
        end

        % Button pushed function: FIVE
        function FIVEButtonPushed(app, event)
            app.response(3) = 4;
            app.enableColumn(4);
        end

        % Button pushed function: FOUND
        function FOUNDButtonPushed(app, event)
            app.response(2) = 2;
            app.enableColumn(3);
        end

        % Button pushed function: FOUR
        function FOURButtonPushed(app, event)
            app.response(3) = 3;
            app.enableColumn(4);
        end

        % Button pushed function: GAVE
        function GAVEButtonPushed(app, event)
            app.response(2) = 3;
            app.enableColumn(3);
        end

        % Button pushed function: GLOVES
        function GLOVESButtonPushed(app, event)
            app.response(5) = 3;
            app.showfeedback();
        end

        % Button pushed function: GREEN
        function GREENButtonPushed(app, event)
            app.response(4) = 3;
            app.enableColumn(5);
        end

        % Button pushed function: HATS
        function HATSButtonPushed(app, event)
            app.response(5) = 4;
            app.showfeedback();
        end

        % Button pushed function: HELD
        function HELDButtonPushed(app, event)
            app.response(2) = 4;
            app.enableColumn(3);
        end

        % Button pushed function: HOT
        function HOTButtonPushed(app, event)
            app.response(4) = 4;
            app.enableColumn(5);
        end

        % Button pushed function: JANE
        function JANEButtonPushed(app, event)
            app.response(1) = 2;
            app.enableColumn(2);
        end

        % Button pushed function: JILL
        function JILLButtonPushed(app, event)
            app.response(1) = 3;
            app.enableColumn(2);
        end

        % Button pushed function: LOST
        function LOSTButtonPushed(app, event)
            app.response(2) = 5;
            app.enableColumn(3);
        end

        % Button pushed function: LYNN
        function LYNNButtonPushed(app, event)
            app.response(1) = 4;
            app.enableColumn(2);
        end

        % Button pushed function: MIKE
        function MIKEButtonPushed(app, event)
            app.response(1) = 5;
            app.enableColumn(2);
        end

        % Button pushed function: NEW
        function NEWButtonPushed(app, event)
            app.response(4) = 5;
            app.enableColumn(5);
        end

        % Button pushed function: NINE
        function NINEButtonPushed(app, event)
            app.response(3) = 7;
            app.enableColumn(4);
        end

        % Button pushed function: OLD
        function OLDButtonPushed(app, event)
            app.response(4) = 6;
            app.enableColumn(5);
        end

        % Button pushed function: PAT
        function PATButtonPushed(app, event)
            app.response(1) = 6;
            app.enableColumn(2);
        end

        % Button pushed function: PENS
        function PENSButtonPushed(app, event)
            app.response(5) = 5;
            app.showfeedback();
        end

        % Button pushed function: RED
        function REDButtonPushed(app, event)
            app.response(4) = 7;
            app.enableColumn(5);
        end

        % Button pushed function: SAM
        function SAMButtonPushed(app, event)
            app.response(1) = 7;
            app.enableColumn(2);
        end

        % Button pushed function: SAW
        function SAWButtonPushed(app, event)
            app.response(2) = 6;
            app.enableColumn(3);
        end

        % Button pushed function: SHOES
        function SHOESButtonPushed(app, event)
            app.response(5) = 6;
            app.showfeedback();
        end

        % Button pushed function: SIX
        function SIXButtonPushed(app, event)
            app.response(3) = 5;
            app.enableColumn(4);
        end

        % Button pushed function: SMALL
        function SMALLButtonPushed(app, event)
            app.response(4) = 8;
            app.enableColumn(5);
        end

        % Button pushed function: SOCKS
        function SOCKSButtonPushed(app, event)
            app.response(5) = 7;
            app.showfeedback();
        end

        % Button pushed function: SOLD
        function SOLDButtonPushed(app, event)
            app.response(2) = 7;
            app.enableColumn(3);
        end

        % Button pushed function: SUE
        function SUEButtonPushed(app, event)
            app.response(1) = 8;
            app.enableColumn(2);
        end

        % Button pushed function: TEN
        function TENButtonPushed(app, event)
            app.response(3) = 8;
            app.enableColumn(4);
        end

        % Button pushed function: THREE
        function THREEButtonPushed(app, event)
            app.response(3) = 2;
            app.enableColumn(4);
        end

        % Button pushed function: TOOK
        function TOOKButtonPushed(app, event)
            app.response(2) = 8;
            app.enableColumn(3);
        end

        % Button pushed function: TOYS
        function TOYSButtonPushed(app, event)
            app.response(5) = 8;
            app.showfeedback();
        end

        % Button pushed function: TWO
        function TWOButtonPushed(app, event)
            app.response(3) = 1;
            app.enableColumn(4);
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            % This function is also called to play each trial, not just the first trial!!
            infoText = 'Follow the voice that starts with "JANE" and ignore the others!';
            app.Info.Text = infoText;
            app.enableColumn(0); % Disable all columns
            if ~app.useTDT
                % When not using TDT
                % Level is not calibrated
                sound(app.currsignal, app.fs);  
            else
                soundTDT(app.currsignal, app.RZ, app.level);
            end
            fprintf(1, 'Running trial # %d, SNR = %f ...\n',...
                app.currtrial, app.currsnr);
            dur = size(app.currsignal, 1)/app.fs;
            pause(dur)
            app.enableColumn(1); % Enable first column with "Jane"
            app.Start.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.BOB = uibutton(app.UIFigure, 'push');
            app.BOB.ButtonPushedFcn = createCallbackFcn(app, @BOBPushed, true);
            app.BOB.Text = 'BOB';
            app.JANE = uibutton(app.UIFigure, 'push');
            app.JANE.ButtonPushedFcn = createCallbackFcn(app, @JANEButtonPushed, true);
            app.JANE.Text = 'JANE';
            app.JILL = uibutton(app.UIFigure, 'push');
            app.JILL.ButtonPushedFcn = createCallbackFcn(app, @JILLButtonPushed, true);
            app.JILL.Text = 'JILL';
            app.LYNN = uibutton(app.UIFigure, 'push');
            app.LYNN.ButtonPushedFcn = createCallbackFcn(app, @LYNNButtonPushed, true);
            app.LYNN.Text = 'LYNN';
            app.MIKE = uibutton(app.UIFigure, 'push');
            app.MIKE.ButtonPushedFcn = createCallbackFcn(app, @MIKEButtonPushed, true);
            app.MIKE.Text = 'MIKE';
            app.PAT = uibutton(app.UIFigure, 'push');
            app.PAT.ButtonPushedFcn = createCallbackFcn(app, @PATButtonPushed, true);
            app.PAT.Text = 'PAT';
            app.BOUGHT = uibutton(app.UIFigure, 'push');
            app.BOUGHT.ButtonPushedFcn = createCallbackFcn(app, @BOUGHTButtonPushed, true);
            app.BOUGHT.Text = 'BOUGHT';
            app.FOUND = uibutton(app.UIFigure, 'push');
            app.FOUND.ButtonPushedFcn = createCallbackFcn(app, @FOUNDButtonPushed, true);
            app.FOUND.Text = 'FOUND';
            app.GAVE = uibutton(app.UIFigure, 'push');
            app.GAVE.ButtonPushedFcn = createCallbackFcn(app, @GAVEButtonPushed, true);
            app.GAVE.Text = 'GAVE';
            app.HELD = uibutton(app.UIFigure, 'push');
            app.HELD.ButtonPushedFcn = createCallbackFcn(app, @HELDButtonPushed, true);
            app.HELD.Text = 'HELD';
            app.LOST = uibutton(app.UIFigure, 'push');
            app.LOST.ButtonPushedFcn = createCallbackFcn(app, @LOSTButtonPushed, true);
            app.LOST.Text = 'LOST';
            app.SAW = uibutton(app.UIFigure, 'push');
            app.SAW.ButtonPushedFcn = createCallbackFcn(app, @SAWButtonPushed, true);
            app.SAW.Text = 'SAW';
            app.TWO = uibutton(app.UIFigure, 'push');
            app.TWO.ButtonPushedFcn = createCallbackFcn(app, @TWOButtonPushed, true);
            app.TWO.Text = 'TWO';
            app.THREE = uibutton(app.UIFigure, 'push');
            app.THREE.ButtonPushedFcn = createCallbackFcn(app, @THREEButtonPushed, true);
            app.THREE.Text = 'THREE';
            app.FOUR = uibutton(app.UIFigure, 'push');
            app.FOUR.ButtonPushedFcn = createCallbackFcn(app, @FOURButtonPushed, true);
            app.FOUR.Text = 'FOUR';
            app.FIVE = uibutton(app.UIFigure, 'push');
            app.FIVE.ButtonPushedFcn = createCallbackFcn(app, @FIVEButtonPushed, true);
            app.FIVE.Text = 'FIVE';
            app.SIX = uibutton(app.UIFigure, 'push');
            app.SIX.ButtonPushedFcn = createCallbackFcn(app, @SIXButtonPushed, true);
            app.SIX.Text = 'SIX';
            app.EIGHT = uibutton(app.UIFigure, 'push');
            app.EIGHT.ButtonPushedFcn = createCallbackFcn(app, @EIGHTButtonPushed, true);
            app.EIGHT.Text = 'EIGHT';
            app.BIG = uibutton(app.UIFigure, 'push');
            app.BIG.ButtonPushedFcn = createCallbackFcn(app, @BIGButtonPushed, true);
            app.BIG.Text = 'BIG';
            app.CHEAP = uibutton(app.UIFigure, 'push');
            app.CHEAP.ButtonPushedFcn = createCallbackFcn(app, @CHEAPButtonPushed, true);
            app.CHEAP.Text = 'CHEAP';
            app.GREEN = uibutton(app.UIFigure, 'push');
            app.GREEN.ButtonPushedFcn = createCallbackFcn(app, @GREENButtonPushed, true);
            app.GREEN.Text = 'GREEN';
            app.HOT = uibutton(app.UIFigure, 'push');
            app.HOT.ButtonPushedFcn = createCallbackFcn(app, @HOTButtonPushed, true);
            app.HOT.Text = 'HOT';
            app.NEW = uibutton(app.UIFigure, 'push');
            app.NEW.ButtonPushedFcn = createCallbackFcn(app, @NEWButtonPushed, true);
            app.NEW.Text = 'NEW';
            app.OLD = uibutton(app.UIFigure, 'push');
            app.OLD.ButtonPushedFcn = createCallbackFcn(app, @OLDButtonPushed, true);
            app.OLD.Text = 'OLD';
            app.BAGS = uibutton(app.UIFigure, 'push');
            app.BAGS.ButtonPushedFcn = createCallbackFcn(app, @BAGSButtonPushed, true);
            app.BAGS.Text = 'BAGS';
            app.CARDS = uibutton(app.UIFigure, 'push');
            app.CARDS.ButtonPushedFcn = createCallbackFcn(app, @CARDSButtonPushed, true);
            app.CARDS.Text = 'CARDS';
            app.GLOVES = uibutton(app.UIFigure, 'push');
            app.GLOVES.ButtonPushedFcn = createCallbackFcn(app, @GLOVESButtonPushed, true);
            app.GLOVES.Text = 'GLOVES';
            app.HATS = uibutton(app.UIFigure, 'push');
            app.HATS.ButtonPushedFcn = createCallbackFcn(app, @HATSButtonPushed, true);
            app.HATS.Text = 'HATS';
            app.PENS = uibutton(app.UIFigure, 'push');
            app.PENS.ButtonPushedFcn = createCallbackFcn(app, @PENSButtonPushed, true);
            app.PENS.Text = 'PENS';
            app.SHOES = uibutton(app.UIFigure, 'push');
            app.SHOES.ButtonPushedFcn = createCallbackFcn(app, @SHOESButtonPushed, true);
            app.SHOES.Text = 'SHOES';
            app.Info = uilabel(app.UIFigure);
            app.Info.Text = 'Please press "Start..." to begin!';
            app.TOYS = uibutton(app.UIFigure, 'push');
            app.TOYS.ButtonPushedFcn = createCallbackFcn(app, @TOYSButtonPushed, true);
            app.TOYS.Text = 'TOYS';
            app.SMALL = uibutton(app.UIFigure, 'push');
            app.SMALL.ButtonPushedFcn = createCallbackFcn(app, @SMALLButtonPushed, true);
            app.SMALL.Text = 'SMALL';
            app.TEN = uibutton(app.UIFigure, 'push');
            app.TEN.ButtonPushedFcn = createCallbackFcn(app, @TENButtonPushed, true);
            app.TEN.Text = 'TEN';
            app.SOCKS = uibutton(app.UIFigure, 'push');
            app.SOCKS.ButtonPushedFcn = createCallbackFcn(app, @SOCKSButtonPushed, true);
            app.SOCKS.Text = 'SOCKS';
            app.RED = uibutton(app.UIFigure, 'push');
            app.RED.ButtonPushedFcn = createCallbackFcn(app, @REDButtonPushed, true);
            app.RED.Text = 'RED';
            app.NINE = uibutton(app.UIFigure, 'push');
            app.NINE.ButtonPushedFcn = createCallbackFcn(app, @NINEButtonPushed, true);
            app.NINE.Text = 'NINE';
            app.TOOK = uibutton(app.UIFigure, 'push');
            app.TOOK.ButtonPushedFcn = createCallbackFcn(app, @TOOKButtonPushed, true);
            app.TOOK.Text = 'TOOK';
            app.SOLD = uibutton(app.UIFigure, 'push');
            app.SOLD.ButtonPushedFcn = createCallbackFcn(app, @SOLDButtonPushed, true);
            app.SOLD.Text = 'SOLD';
            app.SUE = uibutton(app.UIFigure, 'push');
            app.SUE.ButtonPushedFcn = createCallbackFcn(app, @SUEButtonPushed, true);
            app.SUE.Text = 'SUE';
            app.SAM = uibutton(app.UIFigure, 'push');
            app.SAM.ButtonPushedFcn = createCallbackFcn(app, @SAMButtonPushed, true);
            app.SAM.Text = 'SAM';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'Start...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = INFOMASK()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end